classdef ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        DatasetTab           matlab.ui.container.Tab
        Classes              matlab.ui.control.Label
        ObjectsLabel         matlab.ui.control.Label
        Objects              matlab.ui.control.Label
        Features             matlab.ui.control.Label
        Name                 matlab.ui.control.Label
        ClassesLabel         matlab.ui.control.Label
        FeaturesLabel        matlab.ui.control.Label
        NameLabel            matlab.ui.control.Label
        FileNameLabel        matlab.ui.control.Label
        UploaddatasetButton  matlab.ui.control.Button
        ConfigurationTab     matlab.ui.container.Tab
        Tab                  matlab.ui.container.Tab
    end

    
    properties (Access = public)
        col_names
        features
        target_labels
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploaddatasetButton
        function UploaddatasetButtonPushed(app, event)
             [dataset_name, dataset_path] = uigetfile(Const.EXTENSION_FEATURES);
             app.FileNameLabel.Text = dataset_name;
             dataset_full_path = string(dataset_path) + string(dataset_name);
             [app.col_names, app.features, app.target_labels] = import_dataset(dataset_full_path, dataset_name);
             app.Name.Text = app.features.name;
             app.Features.Text = string(app.features.dim);
             app.Objects.Text = string(app.features.num_data);
             app.Classes.Text = string(length(app.target_labels));

             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatasetTab = uitab(app.TabGroup);
            app.DatasetTab.Title = 'Dataset';
            app.UploaddatasetButton = uibutton(app.DatasetTab, 'push');
            app.UploaddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @UploaddatasetButtonPushed, true);
            app.UploaddatasetButton.Text = 'Upload dataset';
            app.FileNameLabel = uilabel(app.DatasetTab);
            app.FileNameLabel.Text = 'None';
            app.NameLabel = uilabel(app.DatasetTab);
            app.NameLabel.Text = 'Name:';
            app.FeaturesLabel = uilabel(app.DatasetTab);
            app.FeaturesLabel.Text = 'Features:';
            app.ClassesLabel = uilabel(app.DatasetTab);
            app.ClassesLabel.Text = 'Classes';
            app.Name = uilabel(app.DatasetTab);
            app.Name.Text = '';
            app.Features = uilabel(app.DatasetTab);
            app.Features.Text = '';
            app.Objects = uilabel(app.DatasetTab);
            app.Objects.Text = '';
            app.ObjectsLabel = uilabel(app.DatasetTab);
            app.ObjectsLabel.Text = 'Objects:';
            app.Classes = uilabel(app.DatasetTab);
            app.Classes.Text = '';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end